classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        SignalFunctinoPanel          matlab.ui.container.Panel
        SignalSelectionPanel         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ADDButton                    matlab.ui.control.Button
        SUBTRACTButton               matlab.ui.control.Button
        GENERATEButton               matlab.ui.control.Button
        CLEARButton                  matlab.ui.control.Button
        SAMPLEButton                 matlab.ui.control.Button
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        TabGroup2                    matlab.ui.container.TabGroup
        DataTab                      matlab.ui.container.Tab
        DataPanel                    matlab.ui.container.Panel
        FFTButton                    matlab.ui.control.Button
        ElementTable                 matlab.ui.control.Table
        SAVEButton                   matlab.ui.control.Button
        FileTypesButtonGroup         matlab.ui.container.ButtonGroup
        TXTButton                    matlab.ui.control.RadioButton
        XLSXButton                   matlab.ui.control.RadioButton
        MainFrequencyEditFieldLabel  matlab.ui.control.Label
        MainFrequencyEditField       matlab.ui.control.NumericEditField
        MainAmplitudeEditFieldLabel  matlab.ui.control.Label
        MainAmplitudeEditField       matlab.ui.control.NumericEditField
        FilterButton                 matlab.ui.control.Button
        ControlTab                   matlab.ui.container.Tab
        ControlCenterPanel           matlab.ui.container.Panel
        RULERKnobLabel               matlab.ui.control.Label
        RULERKnob                    matlab.ui.control.Knob
        AccuracyKnobLabel            matlab.ui.control.Label
        AccuracyKnob                 matlab.ui.control.DiscreteKnob
        RangeKnobLabel               matlab.ui.control.Label
        RangeKnob                    matlab.ui.control.DiscreteKnob
        FilterRangeKnobLabel         matlab.ui.control.Label
        FilterRangeKnob              matlab.ui.control.DiscreteKnob
        MODESwitchLabel              matlab.ui.control.Label
        MODESwitch                   matlab.ui.control.ToggleSwitch
        RightPanel                   matlab.ui.container.Panel
        GraphPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        GENERATESIGNALTab            matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        SamplePanel                  matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        recordPanel = 1 % 记录增加的信号函数数量
        DelList = [] % 记录准备删除的函数数目
        zuobiaochangdu = 40 % 生成信号的长度
        unit = 0.01% 生成信号的最小单元
        begin = -20 % 信号生成的范围
        funcchoice = ["sin", "cos", "guassian", "triangle", "sawtooth", "impulse", "pulse", "square", "rand", "randn"] % 基础信号
        SampleFrequency = 5% 采样信号的默认频率
        recordSignal = []% 记录综合信号
        recordSample = [] % 记录采样信号
        avg % 均值
        smax % 幅值最大值
        smin % 幅值最小值
        msv % 均方值
        va % 方差
        fftN = 50 % fft的N
        len = 4001 % 生成的信号的采样个数
        avgn = 3 % 滑动平均的个数
    end
    
    methods (Access = private)


        function results = squareFunc(app, amplitude, frequency, phase, t)
            y = amplitude * square(frequency * t + phase);
            results = y;
        end
        
        function results = sawtoothFunc(app, amplitude, frequency, phase, t)
            y = amplitude * sawtooth(frequency * t + phase);
            results = y;
        end
        
        function results = sin_func(app, amplitude, frequency, phase, t)
            y = amplitude * sin(frequency * t + phase);
            results = y;
        end
        
        function results = cos_func(app, amplitude, frequency, phase, t)
            y = amplitude * cos(frequency * t + phase);
            results = y;
        end
        
        function results = impulse_func(app, amplitude, frequency, phase, t)
            y = amplitude * impulse_gen(app, frequency * t, phase);
            results = y;
        end
        
        function results = pulse_func(app, amplitude, frequency, phase, t)
            y = amplitude * pulse_gen(app, frequency * t, amplitude, phase);
            results = y;
        end
        
        function results = trianglefunc(app, amplitude, frequency, phase, t)
            y = amplitude * sawtooth(frequency * t + phase, 0.5);
            results = y;
        end
        
        function results = gaus_func(app, amplitude, frequency, phase, t)
            %y = amplitude * gauspuls(t, frequency, phase);
            y = amplitude*(1/(sqrt(2*pi)*frequency)*(exp(-(t-phase).^2/(2*(frequency^2)))));
            results = y;
        end
        
        function axisplot(app, t, dtype)
            
            func1=app.GridLayout3.Children;
            fs = app.recordPanel;
            y=zeros([1, length(t)]);
%             disp(size(y));
%             disp(size(t));
            %disp(func1);
%             disp(fs);
            %disp(size(y));
            for i=1:fs-1
                if (func1((i-1)*5+1).Value=="sin") 
                    y = y + sin_func(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="cos")
                    y = y + cos_func(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="guassian")
                    y = y + gaus_func(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="square")
                    y = y + squareFunc(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="sawtooth")
                    y = y + sawtoothFunc(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="pulse")
                    y = y + pulse_func(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                    %y = y + pulse_gen(app, t, func1((i-1)*5+2).Value, func1((i-1)*5+4).Value);
                elseif (func1((i-1)*5+1).Value=="triangle")
                    y = y + trianglefunc(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                elseif (func1((i-1)*5+1).Value=="rand")
%                     disp(size(rand(1, length(t))));
%                     disp(size(y));
                    y = y + func1((i-1)*5+2).Value * rand(1, length(t)) + func1((i-1)*5+4).Value;
                elseif (func1((i-1)*5+1).Value=="randn")
                    y = y + func1((i-1)*5+2).Value * randn(1, length(t)) + func1((i-1)*5+4).Value;
                else
                    y = y + impulse_func(app, func1((i-1)*5+2).Value, func1((i-1)*5+3).Value, func1((i-1)*5+4).Value, t);
                end
            end
            disp(size(y));
            if (dtype == 1)
                app.recordSignal = y;
                app.avg = mean(y);
                app.smin = min(y);
                app.smax = max(y);
                app.msv = mean(y.*y);
                app.va = mean((y-app.avg).*(y-app.avg));
                if (app.zuobiaochangdu/2 > app.RULERKnob.Limits(1))
%                     disp(class(app.RULERKnob.Limits));
%                     disp(app.RULERKnob.Limits);
                    app.RULERKnob.Value = app.RULERKnob.Limits(1);
                else
                    app.RULERKnob.Value = app.zuobiaochangdu;
                end
                
                tableManager(app);
                plot(app.UIAxes, t, y);
            elseif (isempty(app.recordSignal) ~= 1)
                
                app.recordSample = y;
            end
            
            
        end
        
        function results = pulse_gen(app, t, amplitude, phase)
            results = zeros([1, length(t)]);
            for i=1:length(t)
                if (t(i) > phase)
                    results(i) = results(i) + 1;
                end
            end
            results = results * amplitude;
        end
        
        function results = impulse_gen(app, t, phase)
            results = zeros([1, length(t)]);
            for i=1:length(t)
                if (t(i) < phase && t(i+1) > phase) || (t(i) == phase)
                    results(i) = 2/app.unit;
                    break;
                end
            end
        end
        
        function tableManager(app)
            app.ElementTable.Data = [app.smax, app.smin, app.avg, app.msv, app.va];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            g = app.GridLayout3;
            disp(g.RowHeight);
            disp(app.recordPanel);
            g.RowHeight{app.recordPanel} = 37;
            %add1 = uipanel(g);
            %add1.Title = app.recordPanel;
            %add1.Text = app.recordPanel;
            add1 = uidropdown(g);
            add2 = uieditfield(g, "numeric");
            add3 = uieditfield(g, "numeric");
            add4 = uieditfield(g, "numeric");
            add5 = uicheckbox(g,"Text","DEL", ...
                'ValueChangedFcn',@(add5, event) cBoxChanged(add5));
            add1.Items = app.funcchoice;
            add1.Layout.Column = 1;
            add2.Layout.Column = 2;
            add3.Layout.Column = 3;
            add4.Layout.Column = 4;
            add5.Layout.Column = 5;
            add1.Tooltip = "选择信号的函数类型";
            add2.Tooltip = "幅值或者高度";
            add3.Tooltip = "频率或者宽度";
            add4.Tooltip = "初相位或者带宽";
            add1.Layout.Row = app.recordPanel;
            app.DelList(app.recordPanel) = 1;
            app.recordPanel = app.recordPanel + 1;
            
            function cBoxChanged(cbx)
                app.DelList(cbx.Layout.Row) = 1 - cbx.Value;
                disp(app.DelList);
            end
        end

        % Button pushed function: SUBTRACTButton
        function SUBTRACTButtonPushed(app, event)
            %disp(app.GridLayout3.Children);
            children = app.GridLayout3.Children;
            gadgets = {};
            length = 1;
%             disp(class(children(1)));
            disp(app.recordPanel);
            disp(app.DelList);
            for i=1:app.recordPanel-1
               if (app.DelList(i) == 1)
                   tmp = {};
                   %disp(i);
                   tmp{1} = children((i-1)*5+1).Value;
                   tmp{2} = children((i-1)*5+2).Value;
                   tmp{3} = children((i-1)*5+3).Value;
                   tmp{4} = children((i-1)*5+4).Value;
                   gadgets{length} = tmp;
                   length = length + 1;
                end
            end
            
            app.GridLayout3.clo();
            %disp(size(app.GridLayout3));
            disp(gadgets);
            app.DelList = [];
            g = app.GridLayout3;
            g.RowHeight = {};
            for i=1:length-1
                g.RowHeight{i} = 37;
            end
            disp(length)
            disp(g);
            for i=1:length-1
                add1 = uidropdown(g, ...
                    "Items", app.funcchoice, ...
                    "Value",gadgets{i}{1});
                add2 = uieditfield(g, "numeric", "Value", gadgets{i}{2});
                add3 = uieditfield(g, "numeric", "Value", gadgets{i}{3});
                add4 = uieditfield(g, "numeric", "Value", gadgets{i}{4});
                add5 = uicheckbox(g,"Text","DEL", ...
                    'ValueChangedFcn',@(add5,event) cBoxChanged(add5));
                add1.Layout.Column = 1;
                add2.Layout.Column = 2;
                add3.Layout.Column = 3;
                add4.Layout.Column = 4;
                add5.Layout.Column = 5;
                add1.Tooltip = "选择信号的函数类型";
                add2.Tooltip = "幅值或者高度";
                add3.Tooltip = "频率或者宽度";
                add4.Tooltip = "初相位";
                app.DelList(i) = 1;
            end
            app.recordPanel = length;
            function cBoxChanged(cbx)
                app.DelList(cbx.Layout.Row) = 1 - cbx.Value;
                disp(app.DelList);
            end
            t=app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
            axisplot(app, t, 1);
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            t=app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
            axisplot(app, t, 1);
        end

        % Button pushed function: SAMPLEButton
        function SAMPLEButtonPushed(app, event)
            app.SampleFrequency = app.FrequencyEditField.Value;
            pt = 1/app.SampleFrequency;
            t = app.begin:pt:app.zuobiaochangdu-abs(app.begin);
            axisplot(app, t, 0);
            stem(app.UIAxes2, t, app.recordSample);
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
%             len = floor(length(app.recordSignal)/2);
%             t = 0:1:len;
%             WN = zeros(len);
%             wn = zeros([len, 1]);
%             for n=1:len
%                 t_wn = -n*t*2*pi/(len/2);
%                 WN(n) = exp(wn);
%                 wn(n) = exp(-n)
%             end
%             XN = zeros([len, 1]);
%             YN = zeros([len, 1]);
%             count = 1;
%             for i=1:len*2
%                 if (mod(i+1, 2) == 0)
%                     XN(count) = app.recordSignal(i);
%                     count = count + 1;
%                 else
%                     YN(count) = app.recordSignal(i);
%                 end
%             end
%             XN = XN * WN;
%             YN = YN * WN;
%             FX = zeros([len*2, 1]);

            if (isempty(app.recordSignal) ~= 1)
                atg = app.TabGroup;
                if (length(atg.Children) ~= 2)
                    nt = uitab(atg);
                    nt.AutoResizeChildren = "on";
                    nt.BackgroundColor = [1.00,1.00,1.00];
                    nt.Title = "Fast Fourier Transform";
                    nt.Tooltip = "快速傅里叶变换";
                    ng = uigridlayout(nt);
                    ng.RowHeight = {'1x'};
                    ng.ColumnWidth = {'1x', '1x'};
                    nax = uiaxes(ng);
                    nax.Layout.Row = 1;
                    nax.Layout.Column = 1;
                    nax1 = uiaxes(ng);
                    nax1.Layout.Row = 1;
                    nax1.Layout.Column = 2;
                    %nax.Position = [3, 10, 357, 364];
                    nax.Title.String = "幅值";
                    nax.FontSize = 14;
                    nax.XLabel.String = "f(Hz)";
                    nax1.XLabel.String = "f(Hz)";
                    nax.YLabel.String = "A";
                    nax1.YLabel.String = "φ";
                    nax1.Title.String = "相谱";
                    nax1.FontSize = 14;
                    %nax1.Position = [363, 10, 360, 364];
                    nax.BackgroundColor = [1.00,1.00,1.00];
                    nax1.BackgroundColor = [1.00,1.00,1.00];
                    nax.XGrid = 1;
                    nax.YGrid = 1;
                    nax1.XGrid = 1;
                    nax1.YGrid = 1;
                else
                    %disp(atg.Children(2).Children(1));
                    nax = atg.Children(2).Children(1).Children(1);
                    nax1 = atg.Children(2).Children(1).Children(2);
                end
    
                %n = 2^nextpow2(app.len);
                %Y = fft(app.recordSignal, n);
                Y = fft(app.recordSignal);
                f = (1/app.unit)*(0:(app.len/2))/app.len;
                %f1 = (1/app.unit)*(0:n-1)/n;
                P2 = abs(Y/app.len);
                P3 = imag(Y)./real(Y);
                P3 = atan(P3);
                P4 = P3(1:app.len/2+1);
                %disp(P4);
                %P2 = Y/app.len;
                %disp(P2);
                %disp(length(P2));
                %disp(length(f1));
                P1 = P2(1:app.len/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                [m, index] = max(P1);
                app.MainAmplitudeEditField.Value = m;
                app.MainFrequencyEditField.Value = f(index);
                %stem(nax, f, P1);
                %stem(nax1, f, P4);
                %scatter(nax, f, P1);
                %scatter(nax1, f, P4);
                plot(nax, f, P1);
                %plot(nax1, f, P4);
                scatter(nax1, f, P4, '.');
            end
        end

        % Callback function
        function AccuracyKnob_2ValueChanging(app, event)
            app.MODESwitch.Value = "MANUAL";
            changingValue = event.Value;
            app.unit = changingValue;
            t=app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
            %disp(class(str2double(value)));
            axisplot(app, t, 1);
        %disp(class(changingValue));
        end

        % Value changed function: AccuracyKnob
        function AccuracyKnobValueChanged2(app, event)
            value = app.AccuracyKnob.Value;
            app.unit = str2double(value);
            t=app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
            app.len = length(t);
            %disp(class(str2double(value)));
            axisplot(app, t, 1);
        end

        % Value changed function: RangeKnob
        function RangeKnobValueChanged(app, event)
            value = app.RangeKnob.Value;
            app.MODESwitch.Value = "MANUAL";
            app.zuobiaochangdu = str2double(value);
            app.begin = -(app.zuobiaochangdu/2);
            t=app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
            app.len = length(t);
            %disp(class(str2double(value)));
            axisplot(app, t, 1);
        end

        % Value changing function: RULERKnob
        function RULERKnobValueChanging(app, event)
            changingValue = event.Value;
            disp(changingValue);
            t = -changingValue/2:changingValue/10:changingValue/2;
            app.UIAxes.XTickMode = "manual";
            %disp(t);
            app.UIAxes.XLim = [-changingValue/2, changingValue/2];
            app.UIAxes.XTick = t;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.UIAxes.cla;
            app.UIAxes2.cla;
            app.recordSample = [];
            app.recordSignal = [];
            app.recordPanel = 1;
            app.ElementTable.Data = [];
            app.GridLayout3.clo;
            app.MainAmplitudeEditField.Value = 0;
            app.MainFrequencyEditField.Value = 0;
%             app.UIAxes.XTick = 0:0.1:1;
%             app.UIAxes.YTick = 0:0.2:1;
%             app.UIAxes2.XTick = 0:0.1:1;
%             app.UIAxes2.YTick = 0:0.1:1;        
            app.UIAxes.XLimMode = "auto";
            app.UIAxes.YLimMode = "auto";
            app.UIAxes2.XLimMode = "auto";
            app.UIAxes2.YLimMode = "auto";
            app.UIAxes.XTickMode = "auto";
            app.UIAxes2.XTickMode = "auto";
            app.UIAxes2.YTickMode = "auto";
            app.UIAxes.YTickMode = "auto";
            app.RangeKnob.Value = "40";
            app.AccuracyKnob.Value = "0.01";
            app.RULERKnob.Value = 1;
            app.FilterRangeKnob.Value = "3";
            if (length(app.TabGroup.Children) == 2)
                app.TabGroup.Children(2).Children(1).Children(2).cla();
                app.TabGroup.Children(2).Children(1).Children(1).cla();
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if (isempty(app.recordSignal) ~= 1)
                if (app.TXTButton.Value == 1)
                    [file, path] = uiputfile(".txt");
                    if (~isnumeric(file))
                        tmpd1 = app.ElementTable.Data;
                        t = app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
                        st = app.begin:1/app.SampleFrequency:app.zuobiaochangdu-abs(app.begin);
                        dcell = {"max(最大值)", "min(最小值)", "avg(均值)", "msv(均方值)", "va(方差)";
                            tmpd1(1), tmpd1(2), tmpd1(3), tmpd1(4), tmpd1(5)};
                        writecell(dcell, fullfile(path, file));
                        %writematrix(app.UITable2.Data, fullfile(path, file), "range", "A2");
                        %writematrix(t, fullfile(path, strcat("input", file)));
                        writematrix([t;app.recordSignal], fullfile(path, strcat("datas", file)));
                        if (isempty(app.recordSample) ~= 1)
                            writematrix([st;app.recordSample], fullfile(path, strcat("sample", file)));
                        end
                    end
                else
                    [file, path] = uiputfile(".xlsx");
                    if (~isnumeric(file))
                        tmpd1 = app.ElementTable.Data;
                        t = app.begin:app.unit:app.zuobiaochangdu-abs(app.begin);
                        st = app.begin:1/app.SampleFrequency:app.zuobiaochangdu-abs(app.begin);
                        dcell = {"max(最大值)", "min(最小值)", "avg(均值)", "msv(均方值)", "va(方差)";
                            tmpd1(1), tmpd1(2), tmpd1(3), tmpd1(4), tmpd1(5)};
                        writecell(dcell, fullfile(path, file));
                        %writematrix(app.UITable2.Data, fullfile(path, file), "range", "A2");
                        %writematrix(t, fullfile(path, strcat("input", file)));
                        writematrix([t;app.recordSignal], fullfile(path, file), "Range", "A4");
                        if (isempty(app.recordSample) ~= 1)
                            writematrix([st;app.recordSample], fullfile(path, file), "Range", "A7");
                        end
                    end
                end
            end
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if (isempty(app.recordSignal) ~= 1)
                ny = zeros([1, app.len]);
                for i=1+int16(floor(app.avgn/2)):int16(app.len-floor(app.avgn/2))
                %for i=2:app.len-1
                    %ny(i) = mean(app.recordSignal(i-1:i+1));
                    %disp(class(int16(i-floor(app.avgn/2))));
                    disp(class(i))
                    tmp = int16(i-floor(app.avgn/2)):int16(i+floor(app.avgn/2));
                    disp(tmp);
                    ny(i) = mean(app.recordSignal(tmp));
                end
                for i=1:1+int16(floor(app.avgn/2))
                    %ny(1) = (app.recordSignal(1)+app.recordSignal(2)) / 2;
                    ny(i) = mean(app.recordSignal(1:int16(i+floor(app.avgn/2))));                
                end
                for i=int16(app.len-floor(app.avgn/2)):app.len
                    %ny(app.len) = (app.recordSignal(app.len-1)+app.recordSignal(app.len)) / 2;
                    ny(i) = mean(app.recordSignal(int16(i-floor(app.avgn/2)):app.len));                
                end
                app.recordSignal = ny;
                disp(app.len);
                disp(length(app.recordSignal));
                disp(length(app.begin:app.unit:app.zuobiaochangdu-abs(app.begin)))
                plot(app.UIAxes, (app.begin:app.unit:app.zuobiaochangdu-abs(app.begin)), app.recordSignal);
            end
        end

        % Value changed function: FilterRangeKnob
        function FilterRangeKnobValueChanged(app, event)
            value = app.FilterRangeKnob.Value;
            app.avgn = int16(str2double(value));
        end

        % Value changed function: MODESwitch
        function MODESwitchValueChanged(app, event)
            value = app.MODESwitch.Value;
            app.UIAxes.XLimMode = "auto";
            app.UIAxes.YLimMode = "auto";
            app.UIAxes2.XLimMode = "auto";
            app.UIAxes2.YLimMode = "auto";
            app.UIAxes.XTickMode = "auto";
            app.UIAxes2.XTickMode = "auto";
            app.UIAxes2.YTickMode = "auto";
            app.UIAxes.YTickMode = "auto";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {432, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SignalFunctinoPanel = uipanel(app.GridLayout2);
            app.SignalFunctinoPanel.Title = 'Signal Functino Panel';
            app.SignalSelectionPanel = uipanel(app.SignalFunctinoPanel);
            app.SignalSelectionPanel.Title = 'Signal Selection Panel';
            app.GridLayout3 = uigridlayout(app.SignalSelectionPanel);
            app.ADDButton = uibutton(app.SignalFunctinoPanel, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Tooltip = {'添加信号生成函数'};
            app.ADDButton.Text = 'ADD';
            app.SUBTRACTButton = uibutton(app.SignalFunctinoPanel, 'push');
            app.SUBTRACTButton.ButtonPushedFcn = createCallbackFcn(app, @SUBTRACTButtonPushed, true);
            app.SUBTRACTButton.Tooltip = {'除掉信号生成函数'};
            app.SUBTRACTButton.Text = 'SUBTRACT';
            app.GENERATEButton = uibutton(app.SignalFunctinoPanel, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Tooltip = {'生成信号数据和波形图'};
            app.GENERATEButton.Text = 'GENERATE';
            app.CLEARButton = uibutton(app.SignalFunctinoPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Tooltip = {'清除所有内容'};
            app.CLEARButton.Text = 'CLEAR';
            app.SAMPLEButton = uibutton(app.SignalFunctinoPanel, 'push');
            app.SAMPLEButton.ButtonPushedFcn = createCallbackFcn(app, @SAMPLEButtonPushed, true);
            app.SAMPLEButton.Tooltip = {'采样'};
            app.SAMPLEButton.Text = 'SAMPLE';
            app.FrequencyEditFieldLabel = uilabel(app.SignalFunctinoPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SignalFunctinoPanel, 'numeric');
            app.FrequencyEditField.Tooltip = {'5(default默认值)'};
            app.FrequencyEditField.Value = 5;
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data Tab';
            app.DataPanel = uipanel(app.DataTab);
            app.DataPanel.Title = 'Data';
            app.FFTButton = uibutton(app.DataPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Tooltip = {'快速傅里叶变换'};
            app.FFTButton.Text = 'FFT';
            app.ElementTable = uitable(app.DataPanel);
            app.ElementTable.ColumnName = {'MAX'; 'MIN'; 'AVG'; 'MSV'; 'VA'};
            app.ElementTable.RowName = {};
            app.ElementTable.Tooltip = {'最大值 最小值 均值 均方值 方差'};
            app.SAVEButton = uibutton(app.DataPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Tooltip = {'数据保存到文件'};
            app.SAVEButton.Text = 'SAVE';
            app.FileTypesButtonGroup = uibuttongroup(app.DataPanel);
            app.FileTypesButtonGroup.Tooltip = {'存储的文件格式'};
            app.FileTypesButtonGroup.Title = 'File Types';
            app.TXTButton = uiradiobutton(app.FileTypesButtonGroup);
            app.TXTButton.Text = 'TXT';
            app.TXTButton.Value = true;
            app.XLSXButton = uiradiobutton(app.FileTypesButtonGroup);
            app.XLSXButton.Text = 'XLSX';
            app.MainFrequencyEditFieldLabel = uilabel(app.DataPanel);
            app.MainFrequencyEditFieldLabel.Text = 'Main Frequency';
            app.MainFrequencyEditField = uieditfield(app.DataPanel, 'numeric');
            app.MainFrequencyEditField.Editable = 'off';
            app.MainAmplitudeEditFieldLabel = uilabel(app.DataPanel);
            app.MainAmplitudeEditFieldLabel.Text = 'Main Amplitude';
            app.MainAmplitudeEditField = uieditfield(app.DataPanel, 'numeric');
            app.MainAmplitudeEditField.Editable = 'off';
            app.FilterButton = uibutton(app.DataPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Tooltip = {'滑动均值滤波'};
            app.FilterButton.Text = 'Filter';
            app.ControlTab = uitab(app.TabGroup2);
            app.ControlTab.Title = 'Control Tab';
            app.ControlCenterPanel = uipanel(app.ControlTab);
            app.ControlCenterPanel.Title = 'Control Center';
            app.RULERKnobLabel = uilabel(app.ControlCenterPanel);
            app.RULERKnobLabel.Text = 'RULER';
            app.RULERKnob = uiknob(app.ControlCenterPanel, 'continuous');
            app.RULERKnob.Limits = [0.1 100];
            app.RULERKnob.ValueChangingFcn = createCallbackFcn(app, @RULERKnobValueChanging, true);
            app.RULERKnob.Tooltip = {'调节坐标轴的精细度'};
            app.RULERKnob.Value = 1;
            app.AccuracyKnobLabel = uilabel(app.ControlCenterPanel);
            app.AccuracyKnobLabel.Text = 'Accuracy';
            app.AccuracyKnob = uiknob(app.ControlCenterPanel, 'discrete');
            app.AccuracyKnob.Items = {'0.1', '0.05', '0.01', '0.005', '0.001'};
            app.AccuracyKnob.ValueChangedFcn = createCallbackFcn(app, @AccuracyKnobValueChanged2, true);
            app.AccuracyKnob.Tooltip = {'调节信号的精度'};
            app.AccuracyKnob.Value = '0.01';
            app.RangeKnobLabel = uilabel(app.ControlCenterPanel);
            app.RangeKnobLabel.Text = 'Range';
            app.RangeKnob = uiknob(app.ControlCenterPanel, 'discrete');
            app.RangeKnob.Items = {'1', '5', '10', '20', '40', '100', '200', '500', '2000'};
            app.RangeKnob.ValueChangedFcn = createCallbackFcn(app, @RangeKnobValueChanged, true);
            app.RangeKnob.Tooltip = {'调节信号产生的范围'};
            app.RangeKnob.Value = '40';
            app.FilterRangeKnobLabel = uilabel(app.ControlCenterPanel);
            app.FilterRangeKnobLabel.Text = 'Filter Range';
            app.FilterRangeKnob = uiknob(app.ControlCenterPanel, 'discrete');
            app.FilterRangeKnob.Items = {'3', '5', '10', '15'};
            app.FilterRangeKnob.ValueChangedFcn = createCallbackFcn(app, @FilterRangeKnobValueChanged, true);
            app.FilterRangeKnob.Tooltip = {'滑动均值滤波范围'};
            app.FilterRangeKnob.Value = '3';
            app.MODESwitchLabel = uilabel(app.ControlCenterPanel);
            app.MODESwitchLabel.Text = 'MODE';
            app.MODESwitch = uiswitch(app.ControlCenterPanel, 'toggle');
            app.MODESwitch.Items = {'AUTO', 'MANUAL'};
            app.MODESwitch.ValueChangedFcn = createCallbackFcn(app, @MODESwitchValueChanged, true);
            app.MODESwitch.Value = 'AUTO';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphPanel = uipanel(app.RightPanel);
            app.GraphPanel.Title = 'Graph Panel';
            app.TabGroup = uitabgroup(app.GraphPanel);
            app.GENERATESIGNALTab = uitab(app.TabGroup);
            app.GENERATESIGNALTab.Tooltip = {'信号波形图'};
            app.GENERATESIGNALTab.Title = 'GENERATE SIGNAL';
            app.UIAxes = uiaxes(app.GENERATESIGNALTab);
            title(app.UIAxes, 'INPUT SIGNAL')
            xlabel(app.UIAxes, 'time(t/s)')
            ylabel(app.UIAxes, 'X(t)')
            app.SamplePanel = uipanel(app.RightPanel);
            app.SamplePanel.Title = 'Sample Panel';
            app.UIAxes2 = uiaxes(app.SamplePanel);
            title(app.UIAxes2, 'Sample Signal')
            xlabel(app.UIAxes2, 't(s)')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end