classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DISTANCEAUTOPanel       matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        CHOOSEButton            matlab.ui.control.Button
        CLEARButton             matlab.ui.control.Button
        SUBMITButton            matlab.ui.control.Button
        RESETButton             matlab.ui.control.Button
        DISTANCEMEANSPanel      matlab.ui.container.Panel
        MEANSDropDownLabel      matlab.ui.control.Label
        MEANSDropDown           matlab.ui.control.DropDown
        CLUSTERDropDownLabel    matlab.ui.control.Label
        CLUSTERDropDown         matlab.ui.control.DropDown
        PATHPanel               matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        TextArea                matlab.ui.control.TextArea
        GridLayout7             matlab.ui.container.GridLayout
        READButton              matlab.ui.control.Button
        CLEARButton_2           matlab.ui.control.Button
        GridLayout12            matlab.ui.container.GridLayout
        SAVEEXCELButton         matlab.ui.control.Button
        REREADEXCELButton       matlab.ui.control.Button
        GridLayout3             matlab.ui.container.GridLayout
        EXCELPanel              matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout9             matlab.ui.container.GridLayout
        showmeLabel             matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        GridLayout10            matlab.ui.container.GridLayout
        NUMBEREditFieldLabel    matlab.ui.control.Label
        NUMBEREditField         matlab.ui.control.NumericEditField
        GridLayout11            matlab.ui.container.GridLayout
        ALLCOSTSEditFieldLabel  matlab.ui.control.Label
        ALLCOSTSEditField       matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        UITable2                matlab.ui.control.Table
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        cluster_center % Description
        numc % Description
        sizea % Description
    end
    
    methods (Access = private)
        
        
        function results = func(~, data, means)
            %tmp_s = size(data);
            px = prod(data(:, [1, 3, 4]), 2);
            py = prod(data(:, [2, 3, 4]), 2);
            cost = prod(data(:, 3:4), 2);
            tmp_x = sum(px);
            tmp_y = sum(py);
            tmp_d = sum(cost);
            x_ = tmp_x / tmp_d;
            y_ = tmp_y / tmp_d;
            zuob = [x_, y_];
            dis = pdist2(data(:, 1:2), zuob, means);
            disp(dis);
            disp(cost);
            etc = cost.*dis;
            ttc = sum(etc);
            
            while 1
                nx = sum(px./dis);
                ny = sum(py./dis);
                nzuo = [nx, ny];
                tmp_dis = pdist2(data(:, 1:2), nzuo, means);
                tetc = cost.*tmp_dis;
                ntc = sum(tetc);
                if (ntc > ttc || ttc - ntc < 0.01)
                    break
                else
                    zuob = nzuo;
                    ttc = ntc;
                    etc = tetc;
                    dis = tmp_dis;
                end
     
            end
            results = {zuob, ttc, etc, dis};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHOOSEButton
        function CHOOSEButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.xlsx");
            if ~isnumeric(filename)
                app.TextArea.Value = fullfile(pathname, filename);
            end
            
            %%app.TextArea.Value = pathname;
        end

        % Button pushed function: READButton
        function READButtonPushed(app, event)
            %%disp(app.TextArea.Value);
            value = app.TextArea.Value;
            size1 = size(value);
            %%disp(strcmp(value{1}, ""));
            %%disp(size1(1));
            if ~(size1(1) == 1 && strcmp(value{1}, "") == 1)
                [NUM, TXT, ~] = xlsread(app.TextArea.Value{1});
                %sinum = size(NUM);
                %disp(NUM);
                %%disp(TXT);  'id'    'x'    'y'    'demand'    'cost'
                %disp(RAW);
                %%disp(NUM(:, 2:sinum(2)));
                app.UITable.ColumnName = TXT;
                app.UITable.Data = NUM;
            end
        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            if (strcmp(app.TextArea.Value, "") ~= 1 && app.NUMBEREditField.Value ~= 0 && isempty(app.UITable.Data) ~= 1)
                means = app.MEANSDropDown.Value;
                app.numc = app.NUMBEREditField.Value;
                clusterm = app.CLUSTERDropDown.Value;
                datas = app.UITable.Data;
                sized = size(datas);
                app.sizea = sized;
                if strcmp(means, "kmeans") == 1
                    disp1 = kmeans(datas(:, 2:sized(2)-2), app.numc);
                    app.cluster_center = disp1;
                    disp(disp1);
                    gscatter(app.UIAxes, datas(:, 2), datas(:, 3), disp1, "rgb", "do*");
                elseif strcmp(means, "barycenter") == 1
    
                else
                    dist1 = pdist(datas(:, 2:sized(2)-2), means);
                    distmatrix = squareform(dist1);
                    disp(distmatrix);
                    %disp(clusterm);
                    z = linkage(distmatrix, clusterm);
                    %disp(app.NUMBEREditField.Value);
                    disp(z);
                    %dendrogram(z);
                    t = cluster(z, 'maxclust', app.numc);
                    disp(t);
                    app.cluster_center = t;
                    gscatter(app.UIAxes, datas(:,2), datas(:,3), t);
                end
            end

            
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            app.TextArea.Value = "";
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.UITable.Data = [];
            app.UITable.ColumnName = {};
            app.UITable2.Data = [];
            app.UITable2.ColumnName = {};
            app.UIAxes.cla();
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.MEANSDropDown.Value = "chebychev";
            app.CLUSTERDropDown.Value = "single";
            app.NUMBEREditField.Value = 0;
            app.UITable.Data = [];
            app.UITable.ColumnName = {};
            app.ALLCOSTSEditField.Value = 0;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
                datas = app.UITable.Data;
                ucell = cell(app.numc);
                means = app.MEANSDropDown.Value;
                %disp(isempty(ucell{1}));
                for i=1:app.sizea(1)
                    %ucell{i} = ones(2);
                    disp(isempty(ucell{app.cluster_center(i)}))
                    if (isempty(ucell{app.cluster_center(i)}) == 1)
                        ucell{app.cluster_center(i)} = datas(i, 2:app.sizea(2));
                    else
                        sc = size(ucell{app.cluster_center(i)});
                        disp(sc);
                        disp(ucell{app.cluster_center(i)});
                        disp(datas(i, 2:app.sizea(2)));
                        ucell{app.cluster_center(i)}(sc(1)+1, :) = datas(i, 2:app.sizea(2));
                    end
                end
                %disp(isempty(ucell));
                disp(ucell{1});
                barycenter = cell(app.numc);
                for i=1:app.numc
                    barycenter{i} = app.func(ucell{i}, means);
                end
                disp(size(barycenter));
                disp(barycenter{1});
                hold(app.UIAxes, "on")
                alc = 0;
                for i=1:app.numc
                    scatter(app.UIAxes, barycenter{i}{1}(1), barycenter{i}{1}(2));
                    alc = alc + barycenter{i}{2};
                end
                app.UITable2.ColumnName = ["id", "cluster", "distance", "cost"];
                uidata = zeros(app.sizea(1), 4);
                uidata (:, 1) = uidata (:, 1) + datas(1);
                uidata (:, 2) = uidata (:, 2) + app.cluster_center;
                
                tmp = ones(app.numc, 1);
                for i=1:app.sizea(1)
                    
                    uidata(i, 1) = datas(i, 1);
                    %disp(i);
                    uidata (i, 3) = uidata (i, 3) + barycenter{app.cluster_center(i)}{3}(tmp(app.cluster_center(i)));
                    uidata (i, 4) = uidata (i, 4) + barycenter{app.cluster_center(i)}{4}(tmp(app.cluster_center(i)));
                    tmp(app.cluster_center(i)) = tmp(app.cluster_center(i)) + 1;
                end
                app.UITable2.Data = uidata;
                app.ALLCOSTSEditField.Value = alc;
                
        end

        % Button pushed function: SAVEEXCELButton
        function SAVEEXCELButtonPushed(app, event)
            if (isempty(app.UITable2.Data) ~= 1)
                [file, path] = uiputfile(".xlsx");
                if (~isnumeric(file))
                    writecell(app.UITable2.ColumnName', fullfile(path, file));
                    writematrix(app.UITable2.Data, fullfile(path, file), "range", "A2");
                    
                end
            end

        end

        % Button pushed function: REREADEXCELButton
        function REREADEXCELButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DISTANCEAUTOPanel = uipanel(app.UIFigure);
            app.DISTANCEAUTOPanel.Title = 'DISTANCE AUTO';
            app.GridLayout = uigridlayout(app.DISTANCEAUTOPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.CHOOSEButton = uibutton(app.GridLayout4, 'push');
            app.CHOOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEButtonPushed, true);
            app.CHOOSEButton.Text = 'CHOOSE';
            app.CLEARButton = uibutton(app.GridLayout4, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SUBMITButton = uibutton(app.GridLayout4, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.RESETButton = uibutton(app.GridLayout4, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.DISTANCEMEANSPanel = uipanel(app.GridLayout2);
            app.DISTANCEMEANSPanel.Title = 'DISTANCE MEANS';
            app.MEANSDropDownLabel = uilabel(app.DISTANCEMEANSPanel);
            app.MEANSDropDownLabel.Text = 'MEANS';
            app.MEANSDropDown = uidropdown(app.DISTANCEMEANSPanel);
            app.MEANSDropDown.Items = {'chebychev', 'mahalanobis', 'Largest', 'Smallest', 'cityblock', 'seuclidean', 'kmeans', 'minkowski', 'hamming', 'jaccard', 'barycenter'};
            app.MEANSDropDown.Value = 'chebychev';
            app.CLUSTERDropDownLabel = uilabel(app.DISTANCEMEANSPanel);
            app.CLUSTERDropDownLabel.Text = 'CLUSTER';
            app.CLUSTERDropDown = uidropdown(app.DISTANCEMEANSPanel);
            app.CLUSTERDropDown.Items = {'single', 'complete', 'median', 'centroid', 'average'};
            app.CLUSTERDropDown.Value = 'single';
            app.PATHPanel = uipanel(app.GridLayout2);
            app.PATHPanel.Title = 'PATH';
            app.GridLayout6 = uigridlayout(app.PATHPanel);
            app.TextArea = uitextarea(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.READButton = uibutton(app.GridLayout7, 'push');
            app.READButton.ButtonPushedFcn = createCallbackFcn(app, @READButtonPushed, true);
            app.READButton.Text = 'READ';
            app.CLEARButton_2 = uibutton(app.GridLayout7, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.SAVEEXCELButton = uibutton(app.GridLayout12, 'push');
            app.SAVEEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEEXCELButtonPushed, true);
            app.SAVEEXCELButton.Text = 'SAVE EXCEL';
            app.REREADEXCELButton = uibutton(app.GridLayout12, 'push');
            app.REREADEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @REREADEXCELButtonPushed, true);
            app.REREADEXCELButton.Text = 'REREAD EXCEL';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.EXCELPanel = uipanel(app.GridLayout3);
            app.EXCELPanel.Title = 'EXCEL';
            app.UITable = uitable(app.EXCELPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.showmeLabel = uilabel(app.GridLayout9);
            app.showmeLabel.Text = 'show me';
            app.OKButton = uibutton(app.GridLayout9, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.NUMBEREditFieldLabel = uilabel(app.GridLayout10);
            app.NUMBEREditFieldLabel.Text = 'NUMBER';
            app.NUMBEREditField = uieditfield(app.GridLayout10, 'numeric');
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.ALLCOSTSEditFieldLabel = uilabel(app.GridLayout11);
            app.ALLCOSTSEditFieldLabel.Text = 'ALL COSTS';
            app.ALLCOSTSEditField = uieditfield(app.GridLayout11, 'numeric');
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'DISTRIBUTION OF CLUSTERS')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end